classdef measure_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Lockin_initializeTab            matlab.ui.container.Tab
        lockin1CheckBox                 matlab.ui.control.CheckBox
        lockin2CheckBox                 matlab.ui.control.CheckBox
        lockin3CheckBox                 matlab.ui.control.CheckBox
        lockin_modeEditField            matlab.ui.control.NumericEditField
        lockin_initializeButton         matlab.ui.control.Button
        lockin_resetButton              matlab.ui.control.Button
        lockin_modeDropDownLabel        matlab.ui.control.Label
        lockin_modeDropDown             matlab.ui.control.DropDown
        RsEditFieldLabel                matlab.ui.control.Label
        RsEditField                     matlab.ui.control.NumericEditField
        lockin_nameDropDownLabel        matlab.ui.control.Label
        lockin_nameDropDown             matlab.ui.control.DropDown
        initial_valueEditFieldLabel     matlab.ui.control.Label
        lockin_initial_valueEditField   matlab.ui.control.NumericEditField
        lockin_frequencyEditFieldLabel  matlab.ui.control.Label
        lockin_frequencyEditField       matlab.ui.control.NumericEditField
        sensitivityEditFieldLabel       matlab.ui.control.Label
        lockin_sensitivityEditField     matlab.ui.control.NumericEditField
        Gate_initializeTab              matlab.ui.container.Tab
        gate_initializeButton           matlab.ui.control.Button
        gate_resetButton                matlab.ui.control.Button
        gate_nameDropDownLabel          matlab.ui.control.Label
        gate_nameDropDown               matlab.ui.control.DropDown
        initial_valueEditFieldLabel_2   matlab.ui.control.Label
        gate_initial_valueEditField     matlab.ui.control.NumericEditField
        rangeEditFieldLabel             matlab.ui.control.Label
        gate_rangeEditField             matlab.ui.control.NumericEditField
        modeDropDownLabel               matlab.ui.control.Label
        gate_modeDropDown               matlab.ui.control.DropDown
        timeLabel                       matlab.ui.control.Label
        gate_timeEditField              matlab.ui.control.NumericEditField
        Mag_initializeTab               matlab.ui.container.Tab
        ToDoListPanel                   matlab.ui.container.Panel
        ToDoListBox                     matlab.ui.control.ListBox
        addButton                       matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        messageEditFieldLabel           matlab.ui.control.Label
        messageEditField                matlab.ui.control.EditField
        deleteButton                    matlab.ui.control.Button
        importButton                    matlab.ui.control.Button
        MeasurementPanel                matlab.ui.container.Panel
        scan1_selectionDropDownLabel    matlab.ui.control.Label
        scan1_selectionDropDown         matlab.ui.control.DropDown
        scan2_selectionDropDownLabel    matlab.ui.control.Label
        scan2_selectionDropDown         matlab.ui.control.DropDown
        scan1_rangeEditFieldLabel       matlab.ui.control.Label
        scan1_rangeEditField            matlab.ui.control.EditField
        scan2_rangeEditFieldLabel       matlab.ui.control.Label
        scan2_rangeEditField            matlab.ui.control.EditField
        delayEditFieldLabel             matlab.ui.control.Label
        scan1_delayEditField            matlab.ui.control.NumericEditField
        delayEditFieldLabel_2           matlab.ui.control.Label
        scan2_delayEditField            matlab.ui.control.NumericEditField
        resetLabel                      matlab.ui.control.Label
        scan1_reset_stepEditField       matlab.ui.control.NumericEditField
        resetEditFieldLabel             matlab.ui.control.Label
        scan2_reset_stepEditField       matlab.ui.control.NumericEditField
        timeLabel_2                     matlab.ui.control.Label
        scan1_timeEditField             matlab.ui.control.NumericEditField
        timeLabel_3                     matlab.ui.control.Label
        scan2_timeEditField             matlab.ui.control.NumericEditField
        XY_MappingPanel                 matlab.ui.container.Panel
        x_axisDropDownLabel             matlab.ui.control.Label
        x_axisDropDown                  matlab.ui.control.DropDown
        y_axisDropDownLabel             matlab.ui.control.Label
        y_axisDropDown                  matlab.ui.control.DropDown
        signalDropDownLabel             matlab.ui.control.Label
        signalDropDown                  matlab.ui.control.DropDown
        c_tEditFieldLabel               matlab.ui.control.Label
        c_tEditField                    matlab.ui.control.NumericEditField
        c_bEditFieldLabel               matlab.ui.control.Label
        c_bEditField                    matlab.ui.control.NumericEditField
        x_boundEditFieldLabel           matlab.ui.control.Label
        x_boundEditField                matlab.ui.control.EditField
        y_boundEditFieldLabel           matlab.ui.control.Label
        y_boundEditField                matlab.ui.control.EditField
        x_compEditFieldLabel            matlab.ui.control.Label
        x_compEditField                 matlab.ui.control.EditField
        y_compEditFieldLabel            matlab.ui.control.Label
        y_compEditField                 matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function scan_Initialize(app, type)
            %% initialize the instruments, each time one lockin & one source
            message = [type ' initialization is running!'];
            app.messageEditField.Value = message;
            if strcmp(type, 'lockin')
                lockin_name = app.lockin_nameDropDown.Value;
                if ~strcmp(lockin_name,'none')
                    initial_value = app.lockin_initial_valueEditField.Value;
                    frequency = app.lockin_frequencyEditField.Value;
                    sensitivity = app.lockin_sensitivityEditField.Value;
                    reset_instrument;
                    ch_Initialization(lockin_name, initial_value, frequency);
                    reset_instrument;
                    message='';
                    app.lockin_messageEditField.Value = message;
                else
                    message=[type ' none'];
                    app.messageEditField.Value = message;
                end
            elseif strcmp(type, 'gate')
                gate_name = app.gate_nameDropDown.Value;
                if ~strcmp(gate_name,'none')
                    initial_value = app.gate_initial_valueEditField.Value;
                    range = app.gate_rangeEditField.Value;
                    time = app.gate_timeEditField.Value;
                    reset_instrument;
                    ch_Initialization(gate_name, initial_value, range, time);
                    pause(time);
                    reset_instrument;
                    message = [type ' initialization finished!'];
                    app.messageEditField.Value = message;
                else
                    message=[type ' none'];
                    app.messageEditField.Value = message;
                end
            end
        end

        function reset_measurement(app)
            app.scan1_selectionDropDown.Value = 'none';
            app.scan1_rangeEditField.Value = '-10:0.1:10';
            app.scan1_delayEditField.Value = 10;
            app.scan1_reset_stepEditField.Value = 1;
            
            app.scan2_selectionDropDown.Value = 'none';
            app.scan2_rangeEditField.Value = '-10:0.1:10';
            app.scan2_delayEditField.Value = 10;
            app.scan2_reset_stepEditField.Value = 1;
            
            app.x_axisDropDown.Value = 'none';
            app.c_tEditField.Value = 0;
            app.x_compEditField.Value = '1;0';
            app.x_boundEditField.Value = '-10;10';
            
            app.y_axisDropDown.Value = 'none';
            app.c_bEditField.Value = 0;
            app.y_compEditField.Value = '1;0';
            app.y_boundEditField.Value = '-10;10';
            
            app.signalDropDown.Value = 'lockin1_dI/dV_X (2e^2/h)';
        end
    
        function scan_measurement(app)
            addpath('GS200','SR7270');
            global stop_check
            stop_check = 0;
            reset_instrument;
            
            %% open selected lockin
            select_lockin1 = app.lockin1CheckBox.Value;
            select_lockin2 = app.lockin2CheckBox.Value;
            select_lockin3 = app.lockin3CheckBox.Value;
            
            if select_lockin1 == 1
                lockin1 = instrument_address('lockin1');
                fopen(lockin1);
            else
                lockin1 = 'none';
            end
            if select_lockin2 == 1
                lockin2 = instrument_address('lockin2');
                fopen(lockin2);
            else
                lockin2 = 'none';
            end
            if select_lockin3 == 1
                lockin3 = instrument_address('lockin3');
                fopen(lockin3);
            else
                lockin3 = 'none';
            end
         
            lockin_sensitivity = app.lockin_sensitivityEditField.Value;
            lockin_dV = app.lockin_modeEditField.Value;
            lockin_Rs = app.RsEditField.Value;
            
            %% get instrument name
            test_cur = get_measure_para(app);
            scan1_cur = test_cur.scan1;
            scan2_cur = test_cur.scan2;
            %% instrument initialization
            if strcmp('GS200_top',scan1_cur)
                scan1_type = 'gate';
                scan1_range = eval(test_cur.scan1_range);
                ch_Initialization(scan1_cur, scan1_range(1), 1, 20); % 默认
                pause(20);
%                 Yokogawa_SetRange(scan1_instr, scan1_range(1));
            else
                scan1_type = 'none';
                scan1_range = 0;
            end
            
            if strcmp('GS200_bottom',scan2_cur)
                scan2_type = 'gate';
                scan2_range = eval(test_cur.scan2_range);
                ch_Initialization(scan2_cur, scan2_range(1), 1, 20); % 默认
                pause(20);
%                 Yokogawa_SetRange(scan2_instr, scan2_range);
            else
                scan2_type = 'none';
                scan2_range = 0;
            end
            
            %% create the path of all files and data variables
            path_cur = pwd;
            dir_path = [path_cur '\' date];
            if exist(dir_path) == 0
                mkdir(dir_path);
            end
            test = app.ToDoListBox.Value;
            dir_path = [dir_path '\' test '_' datestr(datetime("now"),30)];
            if exist(dir_path) == 0
                mkdir(dir_path);
            end
            
            %% scan1,scan2:data & file
            x_num = length(scan1_range); % scan1
            y_num = length(scan2_range); % scan2
            Data_scan2 = zeros(1,y_num); 
            Data_scan2_Read = zeros(1,y_num);
            Data_Scan = zeros(y_num, x_num);
            Data_Scan_Read = zeros(y_num,x_num);
            
            filename_Data_scan = [dir_path '\' test '_' ...
                    datestr(datetime("now"),30) '_scan.csv'];
            filename_Data_scan_read = [dir_path '\' app.ToDoListBox.Value '_' ...
                    datestr(datetime("now"),30) '_scan_read.csv'];
            fid_data_scan = fopen(filename_Data_scan, 'a+');
            fid_data_scan_read = fopen(filename_Data_scan_read, 'a+');
            
            %% lockin:data & file
            if select_lockin1 == 1
                Data_lockin1_X = zeros(y_num, x_num);
                Data_lockin1_Y = zeros(y_num, x_num);
                Data_lockin1_R = zeros(y_num, x_num);
                Data_lockin1_theta = zeros(y_num, x_num);
                filename_lockin1 = [dir_path '\' app.ToDoListBox.Value '_' ...
                    datestr(datetime("now"),30) '_lockin1.csv'];
                fid_lockin1 = fopen(filename_lockin1, 'a+');
            else
                filename_lockin1 = 'none';
            end
            
            if select_lockin2 == 1
                Data_lockin2_X = zeros(y_num, x_num);
                Data_lockin2_Y = zeros(y_num, x_num);
                Data_lockin2_R = zeros(y_num, x_num);
                Data_lockin2_theta = zeros(y_num, x_num);
                filename_lockin2 = [dir_path '\' app.ToDoListBox.Value '_' ...
                    datestr(datetime("now"),30) '_lockin2_X.csv'];
                fid_lockin2 = fopen(filename_lockin2_X, 'a+');
            else
                filename_lockin2 = 'none';
            end
            
            if select_lockin3 == 1
                Data_lockin3_X = zeros(y_num, x_num);
                Data_lockin3_Y = zeros(y_num, x_num);
                Data_lockin3_R = zeros(y_num, x_num);
                Data_lockin3_theta = zeros(y_num, x_num);
                filename_lockin3 = [dir_path '\' app.ToDoListBox.Value '_' ...
                    datestr(datetime("now"),30) '_lockin3.csv'];
                fid_lockin3 = fopen(filename_lockin3, 'a+');
            else
                filename_lockin3 = 'none';
            end
            
            %% scanX,scanY:data & file
            %% get scan mode
            if ~strcmp(scan1_cur,'none') & ~strcmp(scan2_cur,'none')
                scan_mode = '2d';
                Data_scanX = zeros(y_num, x_num);
                Data_scanY = zeros(y_num, x_num);
                filename_Data_scanX = [dir_path '\' app.ToDoListBox.Value '_' ...
                    datestr(datetime("now"),30) '_x_axis.csv'];
                filename_Data_scanY = [dir_path '\' app.ToDoListBox.Value '_' ...
                    datestr(datetime("now"),30) '_y_axis.csv'];
                fid_Data_scanX = fopen(filename_Data_scanX, 'a+');
                fid_Data_scanY = fopen(filename_Data_scanY, 'a+');
            elseif strcmp(scan1_cur,'none') & strcmp(scan2_cur,'none')
                scan_mode = 'none';
                return
            else
                scan_mode = '1d';
                Data_scanX = zeros(y_num, x_num);
                Data_scanY = zeros(y_num, x_num);
                if y_num == 1    
                    filename_Data_scanX = [dir_path '\' app.ToDoListBox.Value '_' ...
                        datestr(datetime("now"),30) '_x_axis.csv'];
                    filename_Data_scanY = 'none';
                    fid_Data_scanX = fopen(filename_Data_scanX, 'a+');
                elseif x_num == 1
                    filename_Data_scanX = 'none';
                    filename_Data_scanY = [dir_path '\' app.ToDoListBox.Value '_' ...
                        datestr(datetime("now"),30) '_y_axis.csv'];
                    fid_Data_scanY = fopen(filename_Data_scanY, 'a+');
                end
            end
            
            reset_instrument;
            %% start scanning
            ch_Initialization(scan2_cur, scan2_range(1), 1, 20);
            scan2_instr = instrument_address(scan2_cur);
            for ii = 1:y_num
                if stop_check == 1
                    message = 'stop!';
                    app.messageEditField.Value = message;
                    break;
                end
                
                y = scan2_range(ii);
                ch_set(scan2_cur, scan2_cur, y, 1, 20);
                pause(test_cur.scan2_delay);
                
                real_value = ch_read(scan2_instr, scan2_type);
                Data_scan2(1,ii) = y;
                Data_scan2_Read(1,ii) = real_value;
                
                message = ['scan2:' num2str(y,'%.6f')];
                if ~strcmp('none',scan1_cur)
                    ch_Initialization(scan1_cur, scan1_range(1), 1, 20);
                end
                
                scan1_instr = instrument_address(scan1_cur);
                for jj = 1:x_num
                    x = scan1_range(jj);
                    ch_set(scan1_cur, scan1_instr, x, 1, test_cur.scan1_delay);
                    pause(test_cur.scan1_delay*2);
                    
                    real_value = ch_read(scan1_instr, scan1_type);
                    Data_Scan(ii,jj) = x;
                    Data_Scan_Read(ii,jj) = real_value;
                    
                    if select_lockin1 == 1
                        [X,Y,R,theta] = SR7270_ReadAll(lockin1);
                        Data_lockin1_X(ii,jj) = X;
                        Data_lockin1_Y(ii,jj) = Y;
                        Data_lockin1_R(ii,jj) = R;
                        Data_lockin1_theta(ii,jj) = theta;
                    end
                    if select_lockin2 == 1
                        [X,Y,R,theta] = SR7270_ReadAll(lockin2);
                        Data_lockin2_X(ii,jj) = X;
                        Data_lockin2_Y(ii,jj) = Y;
                        Data_lockin2_R(ii,jj) = R;
                        Data_lockin2_theta(ii,jj) = theta;
                    end
                    if select_lockin3 == 1
                        [X,Y,R,theta] = SR7270_ReadAll(lockin3);
                        Data_lockin3_X(ii,jj) = X;
                        Data_lockin3_Y(ii,jj) = Y;
                        Data_lockin3_R(ii,jj) = R;
                        Data_lockin3_theta(ii,jj) = theta;
                    end
                end

                %% scanX,scanY
                for kk = 1:ii
                    switch test_cur.x_axis
                        case 'n'
                          C_top = test_cur.c_t;
                          C_bottom = test_cur.c_b;
                          
                          Data_scanX(kk,:) = C_top * Data_Scan_Read(kk,:)...
                              + C_bottom * Data_scan2_Read(1,kk);
                        case 'D'
                           C_top = test_cur.c_t;
                           C_bottom = test_cur.c_b;
                           Data_scanX(kk,:) = C_top * Data_Scan_Read(kk,:)...
                              - C_bottom * Data_scan2_Read(1,kk);
                        case 'gate_comp'
                            x_comp = test_cur.x_comp;
                            x_comp = strsplit(x_comp,';');
                            pattern = '-?[0-9_]*\.?[0-9_]{1,}';
                            if length(x_comp) == 2
                                slope = x_comp(1);    
                                slope = slope{1};
                                slope = regexp(slope, pattern, 'match');
                                if length(slope) == 0
                                    slope = 1;
                                else
                                    slope = str2num(slope{1});
                                end
                                
                                intercept = x_comp(2);
                                intercept = intercept{1};
                                intercept = regexp(intercept, pattern, 'match');
                                if length(intercept) == 0
                                    intercept = 0;
                                else
                                    intercept = str2num(intercept{1});
                                end
                            else
                                slope = 1;
                                intercept = 0;
                            end
                            Data_scanX(kk,:) = slope * Data_Scan_Read(kk,:) + intercept;
                        otherwise
                            Data_scanX(kk,:) = Data_Scan_Read(kk,:);
                    end
                    
                    switch test_cur.y_axis
                        case 'n'
                            C_top = test_cur.c_t;
                            C_bottom = test_cur.c_b;
                            Data_scanY(kk,:) = C_top * Data_Scan_Read(kk,:)...
                                + C_bottom * Data_scan2_Read(1,kk);
                        case 'D'
                            C_top = test_cur.c_t;
                            C_bottom = test_cur.c_b;
                            Data_scanY(kk,:) = C_top * Data_scan_Read(kk,:)...
                                - C_bottom * Data_Scan2_Read(1,kk);
                        case 'gate_comp'
                            y_comp = test_cur.y_comp;
                            y_comp = strsplit(y_comp,';');
                            pattern = '-?[0-9_]*\.?[0-9_]{1,}';
                            if length(y_comp) == 2
                                slope = y_comp(1);    
                                slope = slope{1};
                                slope = regexp(slope, pattern, 'match');
                                if length(slope) == 0
                                    slope = 1;
                                else
                                    slope = str2num(slope{1});
                                end
                                
                                intercept = y_comp(2);
                                intercept = intercept{1};
                                intercept = regexp(intercept, pattern, 'match');
                                if length(intercept) == 0
                                    intercept = 0;
                                else
                                    intercept = str2num(intercept{1});
                                end
                            else
                                slope = 1;
                                intercept = 0;
                            end
                            Data_scanY(kk,:) = slope * Data_scan2_Read(1,kk) + intercept;
                        otherwise
                            Data_scanY(kk,:) = Data_scan2_Read(kk,:);
                    end
                end
                
                %%% save data for lockin / external / x_axis / y_axis'
                for jj = 1:x_num
                    fprintf(fid_data_scan,'%d', Data_Scan(ii,jj));
                    fprintf(fid_data_scan_read,'%d', Data_Scan_Read(ii,jj));
                    if ~strcmp('none',filename_lockin1) 
                        fprintf(fid_lockin1, '%d', Data_lockin1_R(ii,jj));
                    end
                    if ~strcmp('none',filename_Data_scanY)
                        fprintf(fid_Data_scanY, Data_scanY(ii,jj));
                    end
                    if ~strcmp('none',filename_Data_scanX)
                        fprintf(fid_Data_scanX, Data_scanX(ii,jj));
                    end
                    if jj == x_num
                        fprintf(fid_data_scan,'\n');
                        fprintf(fid_data_scan_read, '\n');
                        if ~strcmp('none',filename_lockin1)
                            fprintf(fid_lockin1, '\n');
                        end
                        if ~strcmp('none',filename_Data_scanX)
                            fprintf(fid_Data_scanX, '\n');
                        end
                        if ~strcmp('none',filename_Data_scanY)
                            fprintf(fid_Data_scanY, '\n');
                        end
                    end
                end
            end
            
            fclose(filename_Data_scan);
            fclose(filename_Data_scan_read);             
            if ~strcmp('none',filename_lockin1) 
                fclose(fid_lockin1);
            end
            if ~strcmp('none',filename_Data_scanY)
                fclose(fid_Data_scanY);
            end
            if ~strcmp('none',filename_Data_scanX)
                fclose(fid_Data_scanX);
            end
        end
        
        function test_cur = get_measure_para(app)
            scan1_cur = app.scan1_selectionDropDown.Value;
            scan1_range_cur = app.scan1_rangeEditField.Value;
            scan1_delay_cur = app.scan1_delayEditField.Value;
            scan1_rest_cur = app.scan1_reset_stepEditField.Value;
            scan2_cur = app.scan2_selectionDropDown.Value;
            scan2_range_cur = app.scan2_rangeEditField.Value;
            scan2_delay_cur = app.scan2_delayEditField.Value;
            scan2_rest_cur = app.scan2_reset_stepEditField.Value;
            
            x_axis_cur = app.x_axisDropDown.Value;
            c_t_cur = app.c_tEditField.Value;
            x_comp_cur = app.x_compEditField.Value;
            x_bound_cur = app.x_boundEditField.Value;
            
            y_axis_cur = app.y_axisDropDown.Value;
            c_b_cur = app.c_bEditField.Value;
            y_comp_cur = app.y_compEditField.Value;
            y_bound_cur = app.y_boundEditField.Value;
            
            signal_cur = app.signalDropDown.Value;
            
            test_cur = struct('scan1',scan1_cur,'scan2',scan2_cur,...
                'scan1_range',scan1_range_cur,'scan2_range',scan2_range_cur,...
                'scan1_delay',scan1_delay_cur,'scan2_delay',scan2_delay_cur,...
                'scan1_reset',scan1_rest_cur,'scan2_reset',scan2_rest_cur,...
                'x_axis',x_axis_cur,'y_axis',y_axis_cur,'x_comp',x_comp_cur,'y_comp',y_comp_cur,...
                'c_t',c_t_cur,'c_b',c_b_cur,'x_bound',x_bound_cur,'y_bound',y_bound_cur,...
                'signal',signal_cur);
        end
        
        function import_para_from_test(app, test_cur)
            app.scan1_selectionDropDown.Value = test_cur.scan1;
            app.scan1_rangeEditField.Value = test_cur.scan1_range;
            app.scan1_delayEditField.Value = test_cur.scan1_delay;
            app.scan1_reset_stepEditField.Value = test_cur.scan1_reset;
            
            app.scan2_selectionDropDown.Value = test_cur.scan2;
            app.scan2_rangeEditField.Value = test_cur.scan2_range;
            app.scan2_delayEditField.Value = test_cur.scan2_delay;
            app.scan2_reset_stepEditField.Value = test_cur.scan2_reset;
            
            app.x_axisDropDown.Value = test_cur.x_axis;
            app.x_compEditField.Value = test_cur.x_comp;
            app.c_tEditField.Value = test_cur.c_t;
            app.x_boundEditField.Value = test_cur.x_bound;
            
            app.y_axisDropDown.Value = test_cur.y_axis;
            app.y_compEditField.Value = test_cur.y_comp;
            app.c_bEditField.Value = test_cur.c_b;
            app.y_boundEditField.Value = test_cur.y_bound;
            
            app.signalDropDown.Value = test_cur.signal;
        end

    end
    

    methods (Access = private)

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            global test_list
            test_cur = get_measure_para(app);
            test_cur.finish = 0;
            num_cur = length(app.ToDoListBox.Items) + 1;
            str_cur = ['test_' num2str(num_cur)];
            app.ToDoListBox.Items(num_cur) = {str_cur};
            test_list.(str_cur) = test_cur;
            
            reset_measurement(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'start')
                item_num = length(app.ToDoListBox.Items);
                global test_list
                for i = 1:item_num
                    if i > length(app.ToDoListBox.Items)
                        break;
                    end
                    item_cur = app.ToDoListBox.Items(i);
                    test_cur = test_list.(item_cur{1});
                    if test_cur.finish == 1
                        continue;
                    end
                    
                    message = ['start_scanning_' item_cur{1}];
                    app.messageEditField.Value = message;
                    
                    import_para_from_test(app,test_cur);
                    app.ToDoListBox.Value = item_cur{1};
                    scan_measurement(app);
                    
                    message = ['end_scanning_' item_cur{1}];
                    app.messageEditField.Value = message;
                    test_list.(item_cur{1}).finish = 1;
                    pause(5);
                end
            elseif strcmp(value, 'stop')
                global stop_check
                stop_check = 1;
                message = 'stop_scanning';
                app.messageEditField.Value = message;
            end
        end

        % Button pushed function: lockin_initializeButton
        function lockin_initializeButtonPushed(app, event)
            scan_Initialize(app, 'lockin');
        end

        % Button pushed function: lockin_resetButton
        function lockin_resetButtonPushed(app, event)
            app.lockin_nameDropDown.Value = 'none';
            app.lockin_initial_valueEditField.Value = 0;
            app.lockin_frequencyEditField.Value = 500;
            app.lockin_sensitivityEditField.Value = 0;
            app.lockin_modeDropDown.Value = 'dV';
            app.lockin_modeEditField.Value = 0;
            app.RsEditField.Value = 0;
        end

        % Button pushed function: gate_initializeButton
        function gate_initializeButtonPushed(app, event)
            scan_Initialize(app,'gate');
        end

        % Button pushed function: gate_resetButton
        function gate_resetButtonPushed(app, event)
            app.gate_nameDropDown.Value = 'none';
            app.gate_initial_valueEditField.Value = 0;
            app.gate_rangeEditField.Value = 1;
            app.gate_timeEditField.Value = 20;
        end

        % Value changed function: lockin_modeDropDown
        function lockin_modeDropDownValueChanged(app, event)
            value = app.lockin_modeDropDown.Value;
            if strcmp(value,'dV')
                item={'lockin1_dI/dV_X (2e^2/h)', 'lockin1_dI/dV_R (2e^2/h)', 'lockin2_dI/dV_X (2e^2/h)', 'lockin2_dI/dV_R (2e^2/h)', 'lockin3_dI/dV_X (2e^2/h)', 'lockin3_dI/dV_R (2e^2/h)'};
            else
                item={'lockin1_dV/dI_X (kOhm)', 'lockin1_dV/dI_R (kOhm)', 'lockin2_dV/dI_X (kOhm)', 'lockin2_dV/dI_R (kOhm)', 'lockin3_dV/dI_X (kOhm)', 'lockin3_dV/dI_R (kOhm)'}; 
            end
            app.signalDropDown.Items = item;
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            value = app.ToDoListBox.Value;
            [~,idx] = ismember(value,app.ToDoListBox.Items);
            app.ToDoListBox.Items(idx) = [];
            global test_list
            test_list = rmfield(test_list, value);
        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            value = app.ToDoListBox.Value;
            global test_list
            test_cur = test_list.(value);
            import_para_from_test(app, test_cur);
            app.ToDoListBox.Value = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Lockin_initializeTab = uitab(app.TabGroup);
            app.Lockin_initializeTab.Title = 'Lockin_initialize';
            app.lockin1CheckBox = uicheckbox(app.Lockin_initializeTab);
            app.lockin1CheckBox.Text = 'lockin1';
            app.lockin2CheckBox = uicheckbox(app.Lockin_initializeTab);
            app.lockin2CheckBox.Text = 'lockin2';
            app.lockin3CheckBox = uicheckbox(app.Lockin_initializeTab);
            app.lockin3CheckBox.Text = 'lockin3';
            app.lockin_modeEditField = uieditfield(app.Lockin_initializeTab, 'numeric');
            app.lockin_initializeButton = uibutton(app.Lockin_initializeTab, 'push');
            app.lockin_initializeButton.ButtonPushedFcn = createCallbackFcn(app, @lockin_initializeButtonPushed, true);
            app.lockin_initializeButton.Text = 'lockin_initialize';
            app.lockin_resetButton = uibutton(app.Lockin_initializeTab, 'push');
            app.lockin_resetButton.ButtonPushedFcn = createCallbackFcn(app, @lockin_resetButtonPushed, true);
            app.lockin_resetButton.Text = 'lockin_reset';
            app.lockin_modeDropDownLabel = uilabel(app.Lockin_initializeTab);
            app.lockin_modeDropDownLabel.Text = 'lockin_mode';
            app.lockin_modeDropDown = uidropdown(app.Lockin_initializeTab);
            app.lockin_modeDropDown.Items = {'dV', 'dI'};
            app.lockin_modeDropDown.ValueChangedFcn = createCallbackFcn(app, @lockin_modeDropDownValueChanged, true);
            app.lockin_modeDropDown.Value = 'dV';
            app.RsEditFieldLabel = uilabel(app.Lockin_initializeTab);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.Lockin_initializeTab, 'numeric');
            app.lockin_nameDropDownLabel = uilabel(app.Lockin_initializeTab);
            app.lockin_nameDropDownLabel.Text = 'lockin_name';
            app.lockin_nameDropDown = uidropdown(app.Lockin_initializeTab);
            app.lockin_nameDropDown.Items = {'none', 'lockin1', 'lockin2', 'lockin3'};
            app.lockin_nameDropDown.Value = 'none';
            app.initial_valueEditFieldLabel = uilabel(app.Lockin_initializeTab);
            app.initial_valueEditFieldLabel.Text = 'initial_value';
            app.lockin_initial_valueEditField = uieditfield(app.Lockin_initializeTab, 'numeric');
            app.lockin_initial_valueEditField.Limits = [-10 10];
            app.lockin_frequencyEditFieldLabel = uilabel(app.Lockin_initializeTab);
            app.lockin_frequencyEditFieldLabel.Text = 'lockin_frequency';
            app.lockin_frequencyEditField = uieditfield(app.Lockin_initializeTab, 'numeric');
            app.lockin_frequencyEditField.Limits = [500 1500];
            app.lockin_frequencyEditField.Value = 500;
            app.sensitivityEditFieldLabel = uilabel(app.Lockin_initializeTab);
            app.sensitivityEditFieldLabel.Text = 'sensitivity';
            app.lockin_sensitivityEditField = uieditfield(app.Lockin_initializeTab, 'numeric');
            app.Gate_initializeTab = uitab(app.TabGroup);
            app.Gate_initializeTab.Title = 'Gate_initialize';
            app.gate_initializeButton = uibutton(app.Gate_initializeTab, 'push');
            app.gate_initializeButton.ButtonPushedFcn = createCallbackFcn(app, @gate_initializeButtonPushed, true);
            app.gate_initializeButton.Text = 'gate_initialize';
            app.gate_resetButton = uibutton(app.Gate_initializeTab, 'push');
            app.gate_resetButton.ButtonPushedFcn = createCallbackFcn(app, @gate_resetButtonPushed, true);
            app.gate_resetButton.Text = 'gate_reset';
            app.gate_nameDropDownLabel = uilabel(app.Gate_initializeTab);
            app.gate_nameDropDownLabel.Text = 'gate_name';
            app.gate_nameDropDown = uidropdown(app.Gate_initializeTab);
            app.gate_nameDropDown.Items = {'none', 'GS200_top', 'GS200_bottom'};
            app.gate_nameDropDown.Value = 'none';
            app.initial_valueEditFieldLabel_2 = uilabel(app.Gate_initializeTab);
            app.initial_valueEditFieldLabel_2.Text = 'initial_value';
            app.gate_initial_valueEditField = uieditfield(app.Gate_initializeTab, 'numeric');
            app.rangeEditFieldLabel = uilabel(app.Gate_initializeTab);
            app.rangeEditFieldLabel.Text = 'range';
            app.gate_rangeEditField = uieditfield(app.Gate_initializeTab, 'numeric');
            app.gate_rangeEditField.Value = 1;
            app.modeDropDownLabel = uilabel(app.Gate_initializeTab);
            app.modeDropDownLabel.Text = 'mode';
            app.gate_modeDropDown = uidropdown(app.Gate_initializeTab);
            app.gate_modeDropDown.Items = {'Volt', 'Curr'};
            app.gate_modeDropDown.Value = 'Volt';
            app.timeLabel = uilabel(app.Gate_initializeTab);
            app.timeLabel.Text = 'time';
            app.gate_timeEditField = uieditfield(app.Gate_initializeTab, 'numeric');
            app.gate_timeEditField.Limits = [20 Inf];
            app.gate_timeEditField.Value = 20;
            app.Mag_initializeTab = uitab(app.TabGroup);
            app.Mag_initializeTab.Title = 'Mag_initialize';
            app.ToDoListPanel = uipanel(app.UIFigure);
            app.ToDoListPanel.Title = 'To Do List';
            app.ToDoListBox = uilistbox(app.ToDoListPanel);
            app.ToDoListBox.Items = {};
            app.ToDoListBox.Value = {};
            app.addButton = uibutton(app.ToDoListPanel, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.Switch = uiswitch(app.ToDoListPanel, 'slider');
            app.Switch.Items = {'start', 'stop'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'stop';
            app.messageEditFieldLabel = uilabel(app.ToDoListPanel);
            app.messageEditFieldLabel.Text = 'message';
            app.messageEditField = uieditfield(app.ToDoListPanel, 'text');
            app.deleteButton = uibutton(app.ToDoListPanel, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = 'delete';
            app.importButton = uibutton(app.ToDoListPanel, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import';
            app.MeasurementPanel = uipanel(app.UIFigure);
            app.MeasurementPanel.Title = 'Measurement';
            app.scan1_selectionDropDownLabel = uilabel(app.MeasurementPanel);
            app.scan1_selectionDropDownLabel.Text = 'scan1_selection';
            app.scan1_selectionDropDown = uidropdown(app.MeasurementPanel);
            app.scan1_selectionDropDown.Items = {'none', 'lockin1_frequency', 'lockin2_frequency', 'lockin3_frequency', 'GS200_top'};
            app.scan1_selectionDropDown.Value = 'none';
            app.scan2_selectionDropDownLabel = uilabel(app.MeasurementPanel);
            app.scan2_selectionDropDownLabel.Text = 'scan2_selection';
            app.scan2_selectionDropDown = uidropdown(app.MeasurementPanel);
            app.scan2_selectionDropDown.Items = {'none', 'lockin1_frequency', 'lockin2_frequency', 'lockin3_frequency', 'GS200_bottom'};
            app.scan2_selectionDropDown.Value = 'none';
            app.scan1_rangeEditFieldLabel = uilabel(app.MeasurementPanel);
            app.scan1_rangeEditFieldLabel.Text = 'scan1_range';
            app.scan1_rangeEditField = uieditfield(app.MeasurementPanel, 'text');
            app.scan1_rangeEditField.Value = '-10:0.1:10';
            app.scan2_rangeEditFieldLabel = uilabel(app.MeasurementPanel);
            app.scan2_rangeEditFieldLabel.Text = 'scan2_range';
            app.scan2_rangeEditField = uieditfield(app.MeasurementPanel, 'text');
            app.scan2_rangeEditField.Value = '-10:0.1:10';
            app.delayEditFieldLabel = uilabel(app.MeasurementPanel);
            app.delayEditFieldLabel.Text = 'delay';
            app.scan1_delayEditField = uieditfield(app.MeasurementPanel, 'numeric');
            app.scan1_delayEditField.Limits = [10 Inf];
            app.scan1_delayEditField.Value = 10;
            app.delayEditFieldLabel_2 = uilabel(app.MeasurementPanel);
            app.delayEditFieldLabel_2.Text = 'delay';
            app.scan2_delayEditField = uieditfield(app.MeasurementPanel, 'numeric');
            app.scan2_delayEditField.Limits = [10 Inf];
            app.scan2_delayEditField.Value = 10;
            app.resetLabel = uilabel(app.MeasurementPanel);
            app.resetLabel.Text = 'reset';
            app.scan1_reset_stepEditField = uieditfield(app.MeasurementPanel, 'numeric');
            app.scan1_reset_stepEditField.Limits = [1 10000];
            app.scan1_reset_stepEditField.Value = 1;
            app.resetEditFieldLabel = uilabel(app.MeasurementPanel);
            app.resetEditFieldLabel.Text = 'reset';
            app.scan2_reset_stepEditField = uieditfield(app.MeasurementPanel, 'numeric');
            app.scan2_reset_stepEditField.Limits = [1 10000];
            app.scan2_reset_stepEditField.Value = 1;
            app.timeLabel_2 = uilabel(app.MeasurementPanel);
            app.timeLabel_2.Text = 'time';
            app.scan1_timeEditField = uieditfield(app.MeasurementPanel, 'numeric');
            app.scan1_timeEditField.Limits = [20 Inf];
            app.scan1_timeEditField.Value = 20;
            app.timeLabel_3 = uilabel(app.MeasurementPanel);
            app.timeLabel_3.Text = 'time';
            app.scan2_timeEditField = uieditfield(app.MeasurementPanel, 'numeric');
            app.scan2_timeEditField.Limits = [20 Inf];
            app.scan2_timeEditField.Value = 20;
            app.XY_MappingPanel = uipanel(app.UIFigure);
            app.XY_MappingPanel.Title = 'XY_Mapping';
            app.x_axisDropDownLabel = uilabel(app.XY_MappingPanel);
            app.x_axisDropDownLabel.Text = 'x_axis';
            app.x_axisDropDown = uidropdown(app.XY_MappingPanel);
            app.x_axisDropDown.Items = {'none', 'n', 'D', 'gate', 'gate_comp'};
            app.x_axisDropDown.Value = 'none';
            app.y_axisDropDownLabel = uilabel(app.XY_MappingPanel);
            app.y_axisDropDownLabel.Text = 'y_axis';
            app.y_axisDropDown = uidropdown(app.XY_MappingPanel);
            app.y_axisDropDown.Items = {'none', 'n', 'D', 'gate', 'gate_comp'};
            app.y_axisDropDown.Value = 'none';
            app.signalDropDownLabel = uilabel(app.XY_MappingPanel);
            app.signalDropDownLabel.Text = 'signal';
            app.signalDropDown = uidropdown(app.XY_MappingPanel);
            app.signalDropDown.Items = {'lockin1_dI/dV_X (2e^2/h)', 'lockin1_dI/dV_R (2e^2/h)', 'lockin2_dI/dV_X (2e^2/h)', 'lockin2_dI/dV_R (2e^2/h)', 'lockin3_dI/dV_X (2e^2/h)', 'lockin3_dI/dV_R (2e^2/h)'};
            app.signalDropDown.Value = 'lockin1_dI/dV_X (2e^2/h)';
            app.c_tEditFieldLabel = uilabel(app.XY_MappingPanel);
            app.c_tEditFieldLabel.Text = 'c_t';
            app.c_tEditField = uieditfield(app.XY_MappingPanel, 'numeric');
            app.c_bEditFieldLabel = uilabel(app.XY_MappingPanel);
            app.c_bEditFieldLabel.Text = 'c_b';
            app.c_bEditField = uieditfield(app.XY_MappingPanel, 'numeric');
            app.x_boundEditFieldLabel = uilabel(app.XY_MappingPanel);
            app.x_boundEditFieldLabel.Text = 'x_bound';
            app.x_boundEditField = uieditfield(app.XY_MappingPanel, 'text');
            app.x_boundEditField.Value = '-10;10';
            app.y_boundEditFieldLabel = uilabel(app.XY_MappingPanel);
            app.y_boundEditFieldLabel.Text = 'y_bound';
            app.y_boundEditField = uieditfield(app.XY_MappingPanel, 'text');
            app.y_boundEditField.Value = '-10;10';
            app.x_compEditFieldLabel = uilabel(app.XY_MappingPanel);
            app.x_compEditFieldLabel.Text = 'x_comp';
            app.x_compEditField = uieditfield(app.XY_MappingPanel, 'text');
            app.x_compEditField.Value = '1;0';
            app.y_compEditFieldLabel = uilabel(app.XY_MappingPanel);
            app.y_compEditFieldLabel.Text = 'y_comp';
            app.y_compEditField = uieditfield(app.XY_MappingPanel, 'text');
            app.y_compEditField.Value = '1;0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = measure_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end