classdef measure_app_new_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Lockin_InitializationPanel      matlab.ui.container.Panel
        lockin1CheckBox                 matlab.ui.control.CheckBox
        lockin2CheckBox                 matlab.ui.control.CheckBox
        lockin3CheckBox                 matlab.ui.control.CheckBox
        lockin_nameDropDownLabel        matlab.ui.control.Label
        lockin_nameDropDown             matlab.ui.control.DropDown
        initial_valueEditFieldLabel     matlab.ui.control.Label
        lockin_initial_valueEditField   matlab.ui.control.NumericEditField
        lockin_frequencyEditFieldLabel  matlab.ui.control.Label
        lockin_frequencyEditField       matlab.ui.control.NumericEditField
        sensitivityEditFieldLabel       matlab.ui.control.Label
        sensitivityEditField            matlab.ui.control.NumericEditField
        lockin_modeDropDownLabel        matlab.ui.control.Label
        lockin_modeDropDown             matlab.ui.control.DropDown
        lockin_modeEditField            matlab.ui.control.NumericEditField
        RsEditFieldLabel                matlab.ui.control.Label
        RsEditField                     matlab.ui.control.NumericEditField
        lockin_initializeButton         matlab.ui.control.Button
        lockin_resetButton              matlab.ui.control.Button
        messageEditFieldLabel           matlab.ui.control.Label
        lockin_messageEditField         matlab.ui.control.EditField
        Plot_ModePanel                  matlab.ui.container.Panel
        x_axisDropDownLabel             matlab.ui.control.Label
        x_axisDropDown                  matlab.ui.control.DropDown
        y_axisDropDownLabel             matlab.ui.control.Label
        y_axisDropDown                  matlab.ui.control.DropDown
        plot_signalDropDownLabel        matlab.ui.control.Label
        plot_signalDropDown             matlab.ui.control.DropDown
        ShowButton                      matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        addButton                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Gate_InitializationTab          matlab.ui.container.Tab
        gate_nameDropDownLabel          matlab.ui.control.Label
        gate_nameDropDown               matlab.ui.control.DropDown
        rangeEditFieldLabel             matlab.ui.control.Label
        rangeEditField                  matlab.ui.control.NumericEditField
        initial_valueEditFieldLabel_2   matlab.ui.control.Label
        source_initial_valueEditField   matlab.ui.control.NumericEditField
        scan1_selectionDropDownLabel    matlab.ui.control.Label
        scan1_selectionDropDown         matlab.ui.control.DropDown
        scan2_selectionDropDownLabel    matlab.ui.control.Label
        scan2_selectionDropDown         matlab.ui.control.DropDown
        scan1_rangeEditField_2Label     matlab.ui.control.Label
        scan1_rangeEditField            matlab.ui.control.EditField
        scan2_rangeLabel                matlab.ui.control.Label
        scan2_rangeEditField            matlab.ui.control.EditField
        scan1_delayEditFieldLabel       matlab.ui.control.Label
        scan1_delayEditField            matlab.ui.control.NumericEditField
        scan2_delayEditFieldLabel       matlab.ui.control.Label
        scan2_delayEditField            matlab.ui.control.NumericEditField
        scan1_reset_stepEditFieldLabel  matlab.ui.control.Label
        scan1_reset_stepEditField       matlab.ui.control.NumericEditField
        scan2_reset_stepEditFieldLabel  matlab.ui.control.Label
        scan2_reset_stepEditField       matlab.ui.control.NumericEditField
        c_topEditFieldLabel             matlab.ui.control.Label
        c_topEditField                  matlab.ui.control.NumericEditField
        c_bottomEditFieldLabel          matlab.ui.control.Label
        c_bottomEditField               matlab.ui.control.NumericEditField
        gate_initializeButton           matlab.ui.control.Button
        gate_resetButton                matlab.ui.control.Button
        x_compEditFieldLabel            matlab.ui.control.Label
        x_compEditField                 matlab.ui.control.NumericEditField
        y_compEditFieldLabel            matlab.ui.control.Label
        y_compEditField                 matlab.ui.control.NumericEditField
        Mag_InitializationTab           matlab.ui.container.Tab
        ToDoListBoxLabel                matlab.ui.control.Label
        ToDoListBox                     matlab.ui.control.ListBox
    end

    methods (Access = private)

        % Button pushed function: lockin_resetButton
        function lockin_resetButtonPushed(app, event)
            app.lockin_nameDropDown.Value = 'none';
            app.lockin_initial_valueEditField.Value = 0;
            app.lockin_frequencyEditField.Value = 500;
            app.sensitivityEditField.Value = 0;
            app.lockin_modeDropDown.Value = 'dV';
            app.lockin_modeEditField.Value = 0;
            app.RsEditField.Value = 0;
        end

        % Value changed function: lockin_modeDropDown
        function lockin_modeDropDownValueChanged(app, event)
            value = app.lockin_modeDropDown.Value;
            if strcmp(value,'dV')
                item={'lockin1_dI/dV_X (2e^2/h)', 'lockin1_dI/dV_R (2e^2/h)', 'lockin2_dI/dV_X (2e^2/h)', 'lockin2_dI/dV_R (2e^2/h)', 'lockin3_dI/dV_X (2e^2/h)', 'lockin3_dI/dV_R (2e^2/h)'};
            else
                item={'lockin1_dV/dI_X (kOhm)', 'lockin1_dV/dI_R (kOhm)', 'lockin2_dV/dI_X (kOhm)', 'lockin2_dV/dI_R (kOhm)', 'lockin3_dV/dI_X (kOhm)', 'lockin3_dV/dI_R (kOhm)'}; 
            end
            app.plot_signalDropDown.Items = item;
        end

        % Button pushed function: lockin_initializeButton
        function lockin_initializeButtonPushed(app, event)
            scan_Initialize(app,event); 
        end

        % Callback function
        function stopButtonValueChanged(app, event)
            global stop_check
            stop_check=1;
        end

        % Callback function
        function startButtonValueChanged(app, event)
                    if ~strcmp(app.x_axisDropDown.Value,'none') & ~strcmp(app.x_axisDropDown.Value,'none')
                        scan_mode = '2d'
                    elseif strcmp(app.x_axisDropDown.Value,'none') & strcmp(app.x_axisDropDown.Value,'none')
                        scan_mode = 'none'
                    else
                        scan_mode = '1d'
                    end
                    message = 'start_scanning'
                    app.lockin_messageEditField.Value = message;
                    switch scan_mode
                        case '1d'
                            scan1d(app,event,scan_number);
                        case '2d'
                            scan2d(app,event,scan_number);
                        case 'none'
                            'none'
                    end
                    message = 'end_scanning'
                    app.lockin_messageEditField.Value = message;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'start')
                if ~strcmp(app.x_axisDropDown.Value,'none') & ~strcmp(app.x_axisDropDown.Value,'none')
                    scan_mode = '2d'
                elseif strcmp(app.x_axisDropDown.Value,'none') & strcmp(app.x_axisDropDown.Value,'none')
                    scan_mode = 'none';
                else
                    scan_mode = '1d';
                end
                message = 'start_scanning';
                app.lockin_messageEditField.Value = message;
                switch scan_mode
                    case '1d'
                        scan1d(app,event);
                    case '2d'
                        scan2d(app,event);
                end
                message = 'end_scanning';
                app.lockin_messageEditField.Value = message;
            elseif strcmp(value, 'stop')
                global stop_check
                stop_check=1;
                message = 'stop_scanning';
                app.lockin_messageEditField.Value = message;
            end
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            x_axis_cur = app.x_axisDropDown.Value;
            x_name_cur = app.x_nameEditField.Value;
            y_axis_cur = app.y_axisDropDown.Value;
            y_name_cur = app.y_nameEditField.Value;
            test_cur = struct('x_axis',x_axis_cur,'x_name',x_name_cur,'y_axis',y_axis_cur,'y_name',y_name_cur);
            num_cur = length(app.ToDoListBox.Items) + 1;
            str_cur = ['test_' num2str(num_cur)];
            app.ToDoListBox.Items(num_cur) = {str_cur};
%             app.ToDoListBox.ItemsData(num_cur) = test_cur;
        end

        % Button pushed function: gate_initializeButton
        function gate_initializeButtonPushed(app, event)
            
        end

        % Button pushed function: gate_resetButton
        function gate_resetButtonPushed(app, event)
            
        end

        % Value changed function: ToDoListBox
        function ToDoListBoxValueChanged(app, event)

            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Lockin_InitializationPanel = uipanel(app.UIFigure);
            app.Lockin_InitializationPanel.Title = 'Lockin_Initialization';
            app.lockin1CheckBox = uicheckbox(app.Lockin_InitializationPanel);
            app.lockin1CheckBox.Text = 'lockin1';
            app.lockin2CheckBox = uicheckbox(app.Lockin_InitializationPanel);
            app.lockin2CheckBox.Text = 'lockin2';
            app.lockin3CheckBox = uicheckbox(app.Lockin_InitializationPanel);
            app.lockin3CheckBox.Text = 'lockin3';
            app.lockin_nameDropDownLabel = uilabel(app.Lockin_InitializationPanel);
            app.lockin_nameDropDownLabel.Text = 'lockin_name';
            app.lockin_nameDropDown = uidropdown(app.Lockin_InitializationPanel);
            app.lockin_nameDropDown.Items = {'none', 'lockin1', 'lockin2', 'lockin3'};
            app.lockin_nameDropDown.Value = 'none';
            app.initial_valueEditFieldLabel = uilabel(app.Lockin_InitializationPanel);
            app.initial_valueEditFieldLabel.Text = 'initial_value';
            app.lockin_initial_valueEditField = uieditfield(app.Lockin_InitializationPanel, 'numeric');
            app.lockin_initial_valueEditField.Limits = [-10 10];
            app.lockin_frequencyEditFieldLabel = uilabel(app.Lockin_InitializationPanel);
            app.lockin_frequencyEditFieldLabel.Text = 'lockin_frequency';
            app.lockin_frequencyEditField = uieditfield(app.Lockin_InitializationPanel, 'numeric');
            app.lockin_frequencyEditField.Limits = [500 1500];
            app.lockin_frequencyEditField.Value = 500;
            app.sensitivityEditFieldLabel = uilabel(app.Lockin_InitializationPanel);
            app.sensitivityEditFieldLabel.Text = 'sensitivity';
            app.sensitivityEditField = uieditfield(app.Lockin_InitializationPanel, 'numeric');
            app.lockin_modeDropDownLabel = uilabel(app.Lockin_InitializationPanel);
            app.lockin_modeDropDownLabel.Text = 'lockin_mode';
            app.lockin_modeDropDown = uidropdown(app.Lockin_InitializationPanel);
            app.lockin_modeDropDown.Items = {'dV', 'dI'};
            app.lockin_modeDropDown.ValueChangedFcn = createCallbackFcn(app, @lockin_modeDropDownValueChanged, true);
            app.lockin_modeDropDown.Value = 'dV';
            app.lockin_modeEditField = uieditfield(app.Lockin_InitializationPanel, 'numeric');
            app.RsEditFieldLabel = uilabel(app.Lockin_InitializationPanel);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.Lockin_InitializationPanel, 'numeric');
            app.lockin_initializeButton = uibutton(app.Lockin_InitializationPanel, 'push');
            app.lockin_initializeButton.ButtonPushedFcn = createCallbackFcn(app, @lockin_initializeButtonPushed, true);
            app.lockin_initializeButton.Text = 'lockin_initialize';
            app.lockin_resetButton = uibutton(app.Lockin_InitializationPanel, 'push');
            app.lockin_resetButton.ButtonPushedFcn = createCallbackFcn(app, @lockin_resetButtonPushed, true);
            app.lockin_resetButton.Text = 'lockin_reset';
            app.messageEditFieldLabel = uilabel(app.Lockin_InitializationPanel);
            app.messageEditFieldLabel.Text = 'message';
            app.lockin_messageEditField = uieditfield(app.Lockin_InitializationPanel, 'text');
            app.Plot_ModePanel = uipanel(app.UIFigure);
            app.Plot_ModePanel.Title = 'Plot_Mode';
            app.x_axisDropDownLabel = uilabel(app.Plot_ModePanel);
            app.x_axisDropDownLabel.Text = 'x_axis';
            app.x_axisDropDown = uidropdown(app.Plot_ModePanel);
            app.x_axisDropDown.Items = {'none', 'n', 'D', 'gate', 'frequency'};
            app.x_axisDropDown.Value = 'none';
            app.y_axisDropDownLabel = uilabel(app.Plot_ModePanel);
            app.y_axisDropDownLabel.Text = 'y_axis';
            app.y_axisDropDown = uidropdown(app.Plot_ModePanel);
            app.y_axisDropDown.Items = {'none', 'n', 'D', 'gate', 'frequency'};
            app.y_axisDropDown.Value = 'none';
            app.plot_signalDropDownLabel = uilabel(app.Plot_ModePanel);
            app.plot_signalDropDownLabel.Text = 'plot_signal';
            app.plot_signalDropDown = uidropdown(app.Plot_ModePanel);
            app.plot_signalDropDown.Items = {'lockin1_dI/dV_X (2e^2/h)', 'lockin1_dI/dV_R (2e^2/h)', 'lockin2_dI/dV_X (2e^2/h)', 'lockin2_dI/dV_R (2e^2/h)', 'lockin3_dI/dV_X (2e^2/h)', 'lockin3_dI/dV_R (2e^2/h)'};
            app.plot_signalDropDown.Value = 'lockin1_dI/dV_X (2e^2/h)';
            app.ShowButton = uibutton(app.Plot_ModePanel, 'push');
            app.ShowButton.Text = 'Show';
            app.Switch = uiswitch(app.Plot_ModePanel, 'slider');
            app.Switch.Items = {'start', 'stop'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'stop';
            app.addButton = uibutton(app.Plot_ModePanel, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Gate_InitializationTab = uitab(app.TabGroup);
            app.Gate_InitializationTab.Title = 'Gate_Initialization';
            app.gate_nameDropDownLabel = uilabel(app.Gate_InitializationTab);
            app.gate_nameDropDownLabel.Text = 'gate_name';
            app.gate_nameDropDown = uidropdown(app.Gate_InitializationTab);
            app.gate_nameDropDown.Items = {'none', 'GS200_top', 'GS200_bottom'};
            app.gate_nameDropDown.Value = 'none';
            app.rangeEditFieldLabel = uilabel(app.Gate_InitializationTab);
            app.rangeEditFieldLabel.Text = 'range';
            app.rangeEditField = uieditfield(app.Gate_InitializationTab, 'numeric');
            app.initial_valueEditFieldLabel_2 = uilabel(app.Gate_InitializationTab);
            app.initial_valueEditFieldLabel_2.Text = 'initial_value';
            app.source_initial_valueEditField = uieditfield(app.Gate_InitializationTab, 'numeric');
            app.scan1_selectionDropDownLabel = uilabel(app.Gate_InitializationTab);
            app.scan1_selectionDropDownLabel.Text = 'scan1_selection';
            app.scan1_selectionDropDown = uidropdown(app.Gate_InitializationTab);
            app.scan1_selectionDropDown.Items = {'none', 'GS200_top'};
            app.scan1_selectionDropDown.Value = 'none';
            app.scan2_selectionDropDownLabel = uilabel(app.Gate_InitializationTab);
            app.scan2_selectionDropDownLabel.Text = 'scan2_selection';
            app.scan2_selectionDropDown = uidropdown(app.Gate_InitializationTab);
            app.scan2_selectionDropDown.Items = {'none', 'GS200_bottom'};
            app.scan2_selectionDropDown.Value = 'none';
            app.scan1_rangeEditField_2Label = uilabel(app.Gate_InitializationTab);
            app.scan1_rangeEditField_2Label.Text = 'scan1_range';
            app.scan1_rangeEditField = uieditfield(app.Gate_InitializationTab, 'text');
            app.scan2_rangeLabel = uilabel(app.Gate_InitializationTab);
            app.scan2_rangeLabel.Text = 'scan2_range';
            app.scan2_rangeEditField = uieditfield(app.Gate_InitializationTab, 'text');
            app.scan1_delayEditFieldLabel = uilabel(app.Gate_InitializationTab);
            app.scan1_delayEditFieldLabel.Text = 'scan1_delay';
            app.scan1_delayEditField = uieditfield(app.Gate_InitializationTab, 'numeric');
            app.scan2_delayEditFieldLabel = uilabel(app.Gate_InitializationTab);
            app.scan2_delayEditFieldLabel.Text = 'scan2_delay';
            app.scan2_delayEditField = uieditfield(app.Gate_InitializationTab, 'numeric');
            app.scan1_reset_stepEditFieldLabel = uilabel(app.Gate_InitializationTab);
            app.scan1_reset_stepEditFieldLabel.Text = 'scan1_reset_step';
            app.scan1_reset_stepEditField = uieditfield(app.Gate_InitializationTab, 'numeric');
            app.scan2_reset_stepEditFieldLabel = uilabel(app.Gate_InitializationTab);
            app.scan2_reset_stepEditFieldLabel.Text = 'scan2_reset_step';
            app.scan2_reset_stepEditField = uieditfield(app.Gate_InitializationTab, 'numeric');
            app.c_topEditFieldLabel = uilabel(app.Gate_InitializationTab);
            app.c_topEditFieldLabel.Text = 'c_top';
            app.c_topEditField = uieditfield(app.Gate_InitializationTab, 'numeric');
            app.c_bottomEditFieldLabel = uilabel(app.Gate_InitializationTab);
            app.c_bottomEditFieldLabel.Text = 'c_bottom';
            app.c_bottomEditField = uieditfield(app.Gate_InitializationTab, 'numeric');
            app.gate_initializeButton = uibutton(app.Gate_InitializationTab, 'push');
            app.gate_initializeButton.ButtonPushedFcn = createCallbackFcn(app, @gate_initializeButtonPushed, true);
            app.gate_initializeButton.Text = 'gate_initialize';
            app.gate_resetButton = uibutton(app.Gate_InitializationTab, 'push');
            app.gate_resetButton.ButtonPushedFcn = createCallbackFcn(app, @gate_resetButtonPushed, true);
            app.gate_resetButton.Text = 'gate_reset';
            app.x_compEditFieldLabel = uilabel(app.Gate_InitializationTab);
            app.x_compEditFieldLabel.Text = 'x_comp';
            app.x_compEditField = uieditfield(app.Gate_InitializationTab, 'numeric');
            app.y_compEditFieldLabel = uilabel(app.Gate_InitializationTab);
            app.y_compEditFieldLabel.Text = 'y_comp';
            app.y_compEditField = uieditfield(app.Gate_InitializationTab, 'numeric');
            app.Mag_InitializationTab = uitab(app.TabGroup);
            app.Mag_InitializationTab.Title = 'Mag_Initialization';
            app.ToDoListBoxLabel = uilabel(app.UIFigure);
            app.ToDoListBoxLabel.Text = 'ToDo';
            app.ToDoListBox = uilistbox(app.UIFigure);
            app.ToDoListBox.Items = {};
            app.ToDoListBox.ValueChangedFcn = createCallbackFcn(app, @ToDoListBoxValueChanged, true);
            app.ToDoListBox.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = measure_app_new_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end